classdef MaquinaMNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaMNEDUIFigure          matlab.ui.Figure
        AppMenu                      matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DadosDoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nLabel                       matlab.ui.control.Label
        eY0                          matlab.ui.control.NumericEditField
        y0EditFieldLabel             matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aLabel                       matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        fxyLabel                     matlab.ui.control.Label
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        DESATIVARButton              matlab.ui.control.RadioButton
        TODOSButton                  matlab.ui.control.RadioButton
        NEULERMELHORADOButton        matlab.ui.control.RadioButton
        ODE45Button                  matlab.ui.control.RadioButton
        NRK4Button                   matlab.ui.control.RadioButton
        NRK2Button                   matlab.ui.control.RadioButton
        NEULERButton                 matlab.ui.control.RadioButton
        Executar                     matlab.ui.control.Button
        Reset                        matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Executar, nSpinner
        function ExecutarButtonPushed(app, event)
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0 = app.eY0.Value;
            
            %Erro na Funcao
            errID = "MATLAB:Executar:badF";
            msgtext = "Introduza uma função nas variáveis t e y";
            erroFuncao = MException(errID,msgtext);

            %Erro no intervalo
            errID = "MATLAB:Executar:badF";
            msgtext = "Intervalo errado! a > b";
            erroInt = MException(errID,msgtext);

            %Erro no numero de pontos
            errID = "MATLAB:Executar:badF";
            msgtext = "Minimo 2 pontos! n >= 2";
            erroNP = MException(errID,msgtext);
            % % outras exceções

            MNnumericosSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            try
                syms y(t);
                try
	                z = f(t,y);
                    app.eF.BackgroundColor = [1,1,1];
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    throw(erroFuncao);
                end 

                
                if a >= b
                    app.eA.BackgroundColor = [1,0,0];
                    app.eB.BackgroundColor = [1,0,0];
                    throw(erroInt);
                else 
                    app.eA.BackgroundColor = [1,1,1];
                    app.eB.BackgroundColor = [1,1,1];
                    
                end

                if n < 2
                    app.nSpinner.BackgroundColor = [1,0,0];
                    throw(erroNP);
                else
                    app.nSpinner.BackgroundColor = [1,1,1];
                end

                % solução exata do problema
               
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                % solução exata do problema (expressão numérica)
                g = @(t) eval(vectorize(char(sExata)));

                % resultados e tabela de resultados
                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
                [~,yRK4] = NRK4(f,a,b,n,y0);
                [~,yEulerMelhorado] = NEulerMelhorado(f,a,b,n,y0);
                [~,yODE45] = ODE45(f,a,b,n,y0);

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroEulerMelhorado = abs(yExata-yEulerMelhorado);
                erroODE45 = abs(yExata-yODE45);
                plot(app.UIAxes,t,yExata,"-ro",'LineWidth', 1.5);
                legend(app.UIAxes,'Exata');

                hold(app.UIAxes,"on");

                switch MNnumericosSel
                    case "NEULER"
                        plot(app.UIAxes,t,yEuler,"-g*", 'LineWidth', 1);
                        legend(app.UIAxes,'Exata','Euler');

                        A = [t.',yExata.',yEuler.',erroEuler.'];
                        
                        nomeColunas = ["t","Exata","Euler","erroEuler"];
                        
                    case "NRK2"
                        plot(app.UIAxes,t,yRK2,"-b+" , 'LineWidth', 1);
                        legend(app.UIAxes,'Exata',"RK2");

                        A = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas = ["t","Exata","RK2","erroRK2"];
                    case "NRK4"
                        plot(app.UIAxes,t,yRK4,"-y^" ,'LineWidth', 1);
                        legend(app.UIAxes,'Exata',"RK4");
    
                        A = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas = ["t","Exata","RK4","erroRK4"];
                    case "NEULER MELHORADO"
                        plot(app.UIAxes,t,yEulerMelhorado,"-kh" ,'LineWidth', 1);
                        legend(app.UIAxes,'Exata',"EulerMelhorado");
    
                        A = [t.',yExata.',yEulerMelhorado.',erroEulerMelhorado.'];
                        nomeColunas = ["t","Exata","EulerMelhorado","erroEulerMelhorado"];
                    case "ODE45"
                        plot(app.UIAxes,t,yODE45,"-cp" , 'LineWidth', 1);
                        legend(app.UIAxes,'Exata',"ODE45");
    
                        A = [t.',yExata.',yODE45.',erroODE45.'];
                        nomeColunas = ["t","Exata","ODE45","erroODE45"];
                    case "DESATIVAR"
                        plot(app.UIAxes,t,yExata,"-ro",'LineWidth', 1.5);
                        legend(app.UIAxes,'Exata');
                        A = [t.',yExata.'];
                        nomeColunas = ["t","Exata"];
                    case "TODOS"
                        plot(app.UIAxes,t,yEuler,"-g*",'LineWidth', 1);
                        plot(app.UIAxes,t,yRK2,"-b+",'LineWidth', 1);
                        plot(app.UIAxes,t,yRK4,"-y^",'LineWidth', 1);
                        plot(app.UIAxes,t,yEulerMelhorado,"-kh",'LineWidth', 1);
                        plot(app.UIAxes,t,yODE45,"-cp",'LineWidth', 1.5);
                        legend(app.UIAxes,'Exata','Euler',"RK2" , "RK4" , "EulerMelhorado" , "ODE45" , 'Location','best');

                        A = [t.', yExata.', yEuler.', yRK2.', yRK4.', yEulerMelhorado.', yODE45.', erroEuler.', erroRK2.', erroRK4.', erroEulerMelhorado.',erroODE45.'];
                        nomeColunas = ["t","Exata","Euler","RK2" ,"RK4","EulerMelhorado" ,"ODE45" ,"erroEuler" ,"erroRK2" , "erroRK4" , "erroEulerMelhorado" , "erroODE45"];
                    otherwise
                        warndlg("Método não implementado","AVISO","modal");
                        
                        A = [];
                        nomeColunas = [" " , " " ," " ," "];
                end

                hold(app.UIAxes,"off");
                grid(app.UIAxes,"on");

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;

            catch Me
                errordlg(Me.message,"Erro","modal");
            end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
           app.eA.Value = 0;
           app.eB.Value = 0;
           app.nSpinner.Value = 0;
           app.eY0.Value = 0;
           app.eF.Value = " ";
    
           app.NEULERButton.Value = true;

           plot(app.UIAxes,0,0);
           legend(app.UIAxes ,'off');

           A = [];
           nomeColunas = [" " , " " ," " ," "];

           tabelaResultados = array2table(A);
           app.UITable.Data = tabelaResultados;
           app.UITable.ColumnName = nomeColunas;
           app.eA.BackgroundColor = [1,1,1];
           app.eB.BackgroundColor = [1,1,1];
           app.eF.BackgroundColor = [1,1,1];

           
        end

        % Callback function: MaquinaMNEDUIFigure, SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?" , "Sair" , "Sim" , "Nao" , "Sair");
            if strcmp(SN , "Sim")
                delete(app.MaquinaMNEDUIFigure);
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores;
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("Relatorio\AM2_TRABALHO.pdf");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MaquinaMNEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MaquinaMNEDUIFigure = uifigure('Visible', 'off');
            colormap(app.MaquinaMNEDUIFigure, 'white');
            app.MaquinaMNEDUIFigure.Name = 'MaquinaMNED';
            app.MaquinaMNEDUIFigure.Icon = fullfile(pathToMLAPP, 'img', 'math.png');
            app.MaquinaMNEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.MaquinaMNEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AppMenu = uimenu(app.MaquinaMNEDUIFigure);
            app.AppMenu.Text = 'App';
            app.RelatrioMenu = uimenu(app.AppMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.SairMenu = uimenu(app.AppMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.MaquinaMNEDUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MaquinaMNEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.Reset = uibutton(app.GridLayout3, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Icon = fullfile(pathToMLAPP, 'img', 'reset.png');
            app.Reset.Text = '';
            app.Executar = uibutton(app.GridLayout3, 'push');
            app.Executar.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.Executar.Icon = fullfile(pathToMLAPP, 'img', 'data.png');
            app.Executar.Text = '';
            app.MetodosNumericosButtonGroup = uibuttongroup(app.GridLayout3);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.NEULERButton = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.NEULERButton.Text = 'NEULER';
            app.NEULERButton.Value = true;
            app.NRK2Button = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.NRK2Button.Text = 'NRK2';
            app.NRK4Button = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.NRK4Button.Text = 'NRK4';
            app.ODE45Button = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.NEULERMELHORADOButton = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.NEULERMELHORADOButton.Text = 'NEULER MELHORADO';
            app.TODOSButton = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.TODOSButton.Text = 'TODOS';
            app.DESATIVARButton = uiradiobutton(app.MetodosNumericosButtonGroup);
            app.DESATIVARButton.Text = 'DESATIVAR';
            app.DadosDoPVIPanel = uipanel(app.GridLayout3);
            app.DadosDoPVIPanel.Title = 'Dados Do PVI';
            app.fxyLabel = uilabel(app.DadosDoPVIPanel);
            app.fxyLabel.Text = 'F(t,y) =';
            app.eF = uieditfield(app.DadosDoPVIPanel, 'text');
            app.aLabel = uilabel(app.DadosDoPVIPanel);
            app.aLabel.Text = 'a = ';
            app.eA = uieditfield(app.DadosDoPVIPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.DadosDoPVIPanel);
            app.bEditFieldLabel.Text = 'b = ';
            app.eB = uieditfield(app.DadosDoPVIPanel, 'numeric');
            app.y0EditFieldLabel = uilabel(app.DadosDoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0 = ';
            app.eY0 = uieditfield(app.DadosDoPVIPanel, 'numeric');
            app.nLabel = uilabel(app.DadosDoPVIPanel);
            app.nLabel.Text = 'n =';
            app.nSpinner = uispinner(app.DadosDoPVIPanel);
            app.nSpinner.Limits = [0 Inf];
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'white')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MaquinaMNEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaMNEDUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaMNEDUIFigure)
        end
    end
end