classdef Maquina01MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetodosNumericosparaSEDPVIUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RelatorioMenu                matlab.ui.container.Menu
        PaginaWebMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ProblemasDropDown            matlab.ui.control.DropDown
        ProblemasdinmicosLabel       matlab.ui.control.Label
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        gtuvLabel                    matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        aEditFieldLabel_2            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
 
        end

        % Callback function: ExecutarButton, MetodosNumericosButtonGroup, 
        % ...and 1 other component
        function ExecutarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f=@(t,u,v) eval(vectorize(strF));
            display(f);
            strG = app.eG.Value;
            g=@(t,u,v) eval(vectorize(strG));
            display(g);
            display(strF);
            display(strG);
            a = app.eA.Value; b = app.eB.Value; n = app.nSpinner.Value;
            u0 = app.eU0.Value; v0 = app.eV0.Value;

            erroGtuv = MException("MATLAB:Executar:badG","Introduza uma função em t, u e v");
            %outras exceções
            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            try
                try
                    z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch
                    app.eG.BackgroundColor = "r";
                    throw(erroGtuv)
                end

            
                SED = [diff(u,t)==f(t,u,v),diff(v,t)==g(t,u,v)];
                ci  = [u(a)==u0,v(a)==v0];
                [uExata,~] = dsolve(SED,ci);
                h =@(t) eval(vectorize(char(uExata)));


                [t,uEuler,vEuler] = NEulerSED(f,g,a,b,n,u0,v0);
                [~,uRK2,vRK2] = NRK2SED(app,f,g,a,b,n,u0,v0);
                [~,uRK4,vRK4] = NRK4SED(app,f,g,a,b,n,u0,v0);
                [~,uEulerMelhorado,vEulerMelhorado] = NEulerMelhoradoSED(f,g,a,b,n,u0,v0);
               
                
                % Chamar as outras funções/métodos
                if ~isempty(uExata)
                    uExata = h(t);
                    display(uExata);
                    erroEuler = abs(uExata-uEuler);
                    erroRK2 = abs(uExata-uRK2);
                    erroRK4 = abs(uExata-uRK4);
                    erroEulerMelhorado = abs(uExata-uEulerMelhorado);
                    plot(app.UIAxes,t,uExata,"-ro")
                    hold(app.UIAxes,"on")
                    % mais erros
                end


                switch MNumericoSel
                    case "Euler"
                        if ~isempty(uExata)
                            plot(app.UIAxes,t,uEuler,"-g*")
                            legendaGrafico = ["Exata","Euler"];
                            A = [t.',uExata.',uEuler.',erroEuler.'];
                            nomeColunas = ["t","uExata","uEuler","erroEuler"];
                        else
                            plot(app.UIAxes,t,uEuler,"-r*")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,vEuler,"-bo")
                            legendaGrafico = ["AproximaçãoEuler","VelocidadeEuler"];
                            A = [t.',uEuler.',vEuler.'];
                            nomeColunas = ["t","uEuler","vEuler"];
                        end

                    case "Euler M"
                        if ~isempty(uExata)
                            plot(app.UIAxes,t,uEulerMelhorado,'-c*')
                            legendaGrafico = ["Exata","EulerMelhorado"];
                            A = [t.',uExata.',uEulerMelhorado.',erroEulerMelhorado.'];
                            nomeColunas = ["t","uExata","uEulerMelhorado","erroEulerMelhorado"];
                        else
                            plot(app.UIAxes,t,uEulerMelhorado,"-c*")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,vEulerMelhorado,"-bo")
                            legendaGrafico = ["uEulerMelhorado","vEulerMelhorado"];
                            A = [t.',uEulerMelhorado.',vEulerMelhorado.'];
                            nomeColunas = ["t","uEulerMelhorado","vEulerMelhorado"];
                        end
                    case "RK2"
                        if ~isempty(uExata)
                            plot(app.UIAxes,t,uRK2,"-b+")
                            legendaGrafico = ["Exata","RK2"];
                            A = [t.',uExata.',uRK2.',erroRK2.'];
                            nomeColunas = ["t","uExata","uRK2","erroRK2"];
                        else
                            plot(app.UIAxes,t,uRK2,"-y*")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,vRK2,"-bo")
                            legendaGrafico = ["uRK2","vRK2"];
                            A = [t.',uRK2.',vRK2.'];
                            nomeColunas = ["t","uRK2","vRK2"];
                        end
                    case "RK4"
                        if ~isempty(uExata)
                            plot(app.UIAxes,t,uRK4,"-y+")
                            legendaGrafico = ["Exata","RK4"];
                            A = [t.',uExata.',uRK4.',erroRK4.'];
                            nomeColunas = ["t","uExata","uRK4","erroRK4"];
                        else
                            plot(app.UIAxes,t,uRK4,"-g*")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,vRK4,"-bo")
                            legendaGrafico = ["uRK4","vRK4"];
                            A = [t.',uRK4.',vRK4.'];
                            nomeColunas = ["t","uRK4","vRK4"];
                        end
                    case "Todos os Métodos"
                        if ~isempty(uExata)
                            plot(app.UIAxes,t,uEuler,"-g*")
                            plot(app.UIAxes,t,uRK2,"-b+")
                            plot(app.UIAxes,t,uEulerMelhorado,"-cp")
                            plot(app.UIAxes,t,uRK4,"-yo")
                            legendaGrafico = ["Exata","Euler","RK2" ,"RK4" , "EulerMelhorado"];
                            A = [t.',uExata.',uEuler.',uRK2.', uRK4.',uEulerMelhorado.',erroEuler.',erroRK2.' ,erroRK4.' , erroEulerMelhorado.'];
                            nomeColunas = ["t","uExata","uEuler","uRK2","uRK4", "uEulerMelhorado" ,"erroEuler","erroRK2" , "erroRK4" , "erroEulerMelhorado"];    
                        else
                            plot(app.UIAxes, t, uEuler, '-b*', 'DisplayName', 'Euler')
                            hold(app.UIAxes, 'on')
                            plot(app.UIAxes, t, uRK2, '-r^', 'DisplayName', 'RK2')
                            plot(app.UIAxes, t, uRK4, '-g+', 'DisplayName', 'RK4')
                            plot(app.UIAxes, t, uEulerMelhorado, '-m.', 'DisplayName', 'Euler Melhorado')
                            
                            hold(app.UIAxes, 'off')
                            
                            legendaGrafico = ["uEuler", "uRK2", "uRK4", "uEulerMelhorado"];
                            A = [t.', uEuler.', uRK2.', uRK4.', uEulerMelhorado.'];
                            nomeColunas = ["t", "uEuler", "uRK2", "uRK4", "uEulerMelhorado"];
                            
                            legend(app.UIAxes, legendaGrafico, 'Location', 'best')

                        end
               end
                 
                legend(app.UIAxes,legendaGrafico) 
                %grid(app.UIAxes,"on")
                hold(app.UIAxes,"off")

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;
            
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.MetodosNumericosparaSEDPVIUIFigure)
            end
        end

        % Close request function: MetodosNumericosparaSEDPVIUIFigure
        function MetodosNumericosparaSEDPVIUIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("Relatorio\AM2_TRABALHO4_1.pdf")
        end

        % Menu selected function: PaginaWebMenu
        function PaginaWebMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
           autores
        end

        % Value changed function: ProblemasDropDown
        function ProblemasDropDownValueChanged(app, event)
            value = app.ProblemasDropDown.Value;
            k = 0;
            switch value
                case "1"
                    strG = "-16*u";
                    a = 0; b = 8; n = 100; u0 = 9; v0 = 0;
                case "2"
                    strG = "-10*v - 25*u";
                    a = 0; b = 2; n = 100; u0 = 0; v0 = -4;
                case "3"
                    strG = "-sin(u)-0.3*v";
                    a = 0; b = 15; n = 100; u0 = pi/2; v0 = 0;
                case "4"
                    strG = "-5*v - 4*u + 2*exp(-t/4)";
                    a = 0; b = 10; n = 100; u0 = 0; v0 = 0;
                case "5"
                    strG = "-3.2*v - 5.12*u";
                    a = 0; b = 10; n = 100; u0 = 0.5; v0 = 0;
                case "6"
                    k = 1;
                    strG = "";
                    a = 0; b = 0; u0 = 0; v0 = 0;
                    app.eG.Value = strG;
                    app.eA.Value = a; app.eB.Value = b; app.nSpinner.Limits = [0 100]; app.nSpinner.Value = 0;
                    app.eU0.Value= u0; app.eV0.Value = v0;
                    plot(app.UIAxes, 0, 0);
                    legend(app.UIAxes, 'off');
                    A = [];
                    tabelaResultados = array2table(A);
                    app.UITable.Data = tabelaResultados;
                    nomeColunas = [];
                    app.UITable.ColumnName = nomeColunas;
            end
         
            if k == 0
                app.eG.Value = strG;
                app.eA.Value = a; app.eB.Value = b; app.nSpinner.Value = n; 
                app.eU0.Value= u0; app.eV0.Value = v0;
                ExecutarButtonPushed(app, event)  
            end
            
        end

        % Callback function
        function LimparCamposButtonPushed(app, event)
            strG = "";
            a = 0; b = 0; u0 = 0; v0 = 0;
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.nSpinner.Limits = [0 100]; app.nSpinner.Value = 0;
            app.eU0.Value= u0; app.eV0.Value = v0;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ProblemasDropDown.Value = "1";
            app.EulerButton.Value = true;
            ProblemasDropDownValueChanged(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MetodosNumericosparaSEDPVIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {466, 466};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {223, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetodosNumericosparaSEDPVIUIFigure = uifigure('Visible', 'off');
            app.MetodosNumericosparaSEDPVIUIFigure.Name = 'Metodos Numericos para SED/PVI';
            app.MetodosNumericosparaSEDPVIUIFigure.CloseRequestFcn = createCallbackFcn(app, @MetodosNumericosparaSEDPVIUIFigureCloseRequest, true);
            app.MetodosNumericosparaSEDPVIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.MetodosNumericosparaSEDPVIUIFigure);
            app.FileMenu.Text = 'File';
            app.RelatorioMenu = uimenu(app.FileMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaWebMenu = uimenu(app.FileMenu);
            app.PaginaWebMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaWebMenuSelected, true);
            app.PaginaWebMenu.Text = 'PáginaWeb';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.MetodosNumericosparaSEDPVIUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MetodosNumericosparaSEDPVIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.DadosdoPVIPanel = uipanel(app.GridLayout3);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [2 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 = ';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.MetodosNumericosButtonGroup = uibuttongroup(app.GridLayout3);
            app.MetodosNumericosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.GridLayout3, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.GridLayout3);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.ProblemasdinmicosLabel = uilabel(app.GridLayout3);
            app.ProblemasdinmicosLabel.Text = 'Problemas dinâmicos';
            app.ProblemasDropDown = uidropdown(app.GridLayout3);
            app.ProblemasDropDown.Items = {'Mola Massa sem amortecimento', 'Mola Massa com Amortecimento', 'Pêndulo', 'Circuito Elétrico', 'Aterragem de uma Sonda Lunar', 'outro'};
            app.ProblemasDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ProblemasDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasDropDownValueChanged, true);
            app.ProblemasDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MetodosNumericosparaSEDPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetodosNumericosparaSEDPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetodosNumericosparaSEDPVIUIFigure)
        end
    end
end