classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaCDIUIFigure        matlab.ui.Figure
        AppMenu                   matlab.ui.container.Menu
        RelatrioMenu              matlab.ui.container.Menu
        SairMenu                  matlab.ui.container.Menu
        SobreMenu                 matlab.ui.container.Menu
        AutoresMenu               matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        tgFrVr                    matlab.ui.container.Tab
        pResultados               matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
        pDados                    matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        DerivaoNumricaTab         matlab.ui.container.Tab
        DiferenasFinitasLabel     matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        DerivadaButton            matlab.ui.control.RadioButton
        Regressiva3pontosButton   matlab.ui.control.RadioButton
        Progressiva3pontosButton  matlab.ui.control.RadioButton
        CentradaButton            matlab.ui.control.RadioButton
        Regressiva2pontosButton   matlab.ui.control.RadioButton
        Progressiva2pontosButton  matlab.ui.control.RadioButton
        IntegraoNumricaTab        matlab.ui.container.Tab
        IntegraesNumricasLabel    matlab.ui.control.Label
        ResultadoLabel            matlab.ui.control.Label
        eIntN                     matlab.ui.control.NumericEditField
        RegraListBox              matlab.ui.control.ListBox
        dydx                      matlab.ui.control.Label
        bAtualizar                matlab.ui.control.Button
        DadosFunoPanel            matlab.ui.container.Panel
        bLabel                    matlab.ui.control.Label
        eB                        matlab.ui.control.NumericEditField
        hLabel                    matlab.ui.control.Label
        eH                        matlab.ui.control.NumericEditField
        eA                        matlab.ui.control.NumericEditField
        aLabel                    matlab.ui.control.Label
        eF                        matlab.ui.control.EditField
        fxLabel                   matlab.ui.control.Label
        tgFr2Vr                   matlab.ui.container.Tab
        pResultados_2             matlab.ui.container.Panel
        UIAxes_2                  matlab.ui.control.UIAxes
        pDados_2                  matlab.ui.container.Panel
        harmonica                 matlab.ui.control.Label
        dydx2                     matlab.ui.control.Label
        eHarmonica                matlab.ui.control.EditField
        bAtualizar_2              matlab.ui.control.Button
        DadosFunoPanel_2          matlab.ui.container.Panel
        eB_2                      matlab.ui.control.NumericEditField
        bEditFieldLabel_2         matlab.ui.control.Label
        eH1                       matlab.ui.control.NumericEditField
        h1EditFieldLabel_2        matlab.ui.control.Label
        eA_2                      matlab.ui.control.NumericEditField
        aEditFieldLabel_2         matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        eD                        matlab.ui.control.NumericEditField
        eH2                       matlab.ui.control.NumericEditField
        eC                        matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        h1EditFieldLabel          matlab.ui.control.Label
        aEditFieldLabel           matlab.ui.control.Label
        yLabel                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        eF_2                      matlab.ui.control.EditField
        fxyLabel                  matlab.ui.control.Label
    end

    
    methods (Access = public)

            function YN = isHarmonica(~,f)
                syms z(x,y)
                if (diff(f,x,2)+diff(f,y,2)==0)
                    YN = 1;
                else
                    YN = 0;
                end
            end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; h = app.eH.Value; b = app.eB.Value; 
           
            try
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                DF = app.ButtonGroup.SelectedObject.Text;
    
               switch DF
                    case "Progressiva (2 pontos)"
                        [x, y, dydxDFP2] = Progressiva2p(app, f, a, b, h);
                        dydxExata = g(x);
                        erroDFP2 = abs(dydxExata - dydxDFP2);
                        tabela = array2table([x.', y.', dydxExata.', dydxDFP2.', erroDFP2.']);
                        NomeColunas = {'x', 'y', 'Exata', 'Progressiva (2 pontos)', 'erroProgressiva (2 pontos)'};
                        app.UITable.Data = tabela;
                        app.UITable.ColumnName = NomeColunas;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDFP2, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', 'Progressiva (2 pontos)')
                        
                    case "Regressiva (2 pontos)"
                        [x, y, dydxDFR2] = Regressiva2p(app, f, a, b, h);
                        dydxExata = g(x);
                        erroDFR2 = abs(dydxExata - dydxDFR2);
                        NomeColunas = {'x', 'y', 'Exata', 'Regressiva (2 pontos)', 'erroRegressiva (2 pontos)'};
                        tabela = array2table([x.', y.', dydxExata.', dydxDFR2.', erroDFR2.']);
                        app.UITable.ColumnName = NomeColunas;
                        app.UITable.Data = tabela;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDFR2, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', 'Regressiva (2 pontos)')
                        
                    case "Centrada"
                        [x, y, dydxDFC] = Centrada(app, f, a, b, h);
                        dydxExata = g(x);
                        erroDFC = abs(dydxExata - dydxDFC);
                        tabela = array2table([x.', y.', dydxExata.', dydxDFC.', erroDFC.']);
                        NomeColunas = {'x', 'y', 'Exata', 'Centrada', 'erroCentrada'};
                        app.UITable.Data = tabela;
                        app.UITable.ColumnName = NomeColunas;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDFC, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', 'Centrada')
                        
                    case "Progressiva (3 pontos)"
                        [x, y, dydxDFP3] = Progressiva3p(f, a, b, h);
                        dydxExata = g(x);
                        erroDFP3 = abs(dydxExata - dydxDFP3);
                        tabela = array2table([x.', y.', dydxExata.', dydxDFP3.', erroDFP3.']);
                        NomeColunas = {'x', 'y', 'Exata', 'Progressiva (3 pontos)', 'erroProgressiva (3 pontos)'};
                        app.UITable.Data = tabela;
                        app.UITable.ColumnName = NomeColunas;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDFP3, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', 'Progressiva (3 pontos)')
                        
                    case "Regressiva (3 pontos)"
                        [x, y, dydxDFR3] = Regressiva3p(f, a, b, h);
                        dydxExata = g(x);
                        erroDFR3 = abs(dydxExata - dydxDFR3);
                        NomeColunas = {'x', 'y', 'Exata', 'Regressiva (3 pontos)', 'erroRegressiva (3 pontos)'};
                        tabela = array2table([x.', y.', dydxExata.', dydxDFR3.', erroDFR3.']);
                        app.UITable.ColumnName = NomeColunas;
                        app.UITable.Data = tabela;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDFR3, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', 'Regressiva (3 pontos)')
                        
                    case "2ºDerivada"
                        [x, y, dydxDF2D] = SDerivada(f, a, b, h);
                        dydxExata = g(x);
                        erroDF2D = abs(dydxExata - dydxDF2D);
                        NomeColunas = {'x', 'y', 'Exata', '2_Derivada', 'erro2_Derivada'};
                        tabela = array2table([x.', y.', dydxExata.', dydxDF2D.', erroDF2D.']);
                        app.UITable.ColumnName = NomeColunas;
                        app.UITable.Data = tabela;
                        plot(app.UIAxes, x, y, "-r+")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes, x, dydxExata, "-bo")
                        plot(app.UIAxes, x, dydxDF2D, "-g*")
                        hold(app.UIAxes, "off")
                        legend(app.UIAxes, 'f(x)', 'Derivada Exata', '2ª Derivada')
                end


                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                app.dydx.Text = formaLatex;
                
                sExata = int(f(sym('x')),a,b);
                q = quad(f,a,b);
                
                n = (b-a)/h;

                Regra = app.RegraListBox.Value;
                switch Regra
                    case "1"
                        S = RegraTrapezios(f,a,b,n);
                        app.eIntN.Value = S;
                    case "2"
                        S = RegraSimpson(f,a,b,n);
                        app.eIntN.Value = S;
                end
                
                
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end

            
        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            syms x y
            strFxy = app.eF_2.Value;
            f = @(x,y) eval(vectorize(strFxy));
            a = app.eA_2.Value; h1 = app.eH1.Value; b = app.eB_2.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            [Gx,Gy] = meshgrid(a:h1:b, c:h2:d);
            z = f(Gx,Gy);
          
            % Convert string expression to symbolic expression
          
            display(strFxy)
        
            eq1 = "$ \frac{\partial^2 f}{\partial x^2} +  \frac{\partial^2 f}{\partial y^2}\neq 0$";
            eq2 = "$ \frac{\partial^2 f}{\partial x^2} +  \frac{\partial^2 f}{\partial y^2} = 0$";
            mesh(app.UIAxes_2, Gx, Gy, z)
            
            % Compute symbolic derivative
            dzdxSym = diff(f,x);
            dzdySym = diff(f,y);
            
            if isHarmonica(app, f)
                strHarmonica = "f é harmónica";
                corHarmonica = "g";
                app.harmonica.Text = eq1;
            else
                strHarmonica = "f não é harmónica";
                corHarmonica = "r";    
                app.harmonica.Text = eq2;
            end
            app.eHarmonica.Value = strHarmonica;
            app.eHarmonica.FontColor = corHarmonica;
            
            formaLatex = ['$\frac{dz}{dx}=', latex(dzdxSym), '\\\frac{dz}{dy}=', latex(dzdySym) ,'$'];
            app.dydx2.Text = formaLatex;

            
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores;
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
           open("Relatorio\AM2_TRABALHO5_1.pdf");
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.MaquinaCDIUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MaquinaCDIUIFigure.Name = 'MaquinaCDI';
            app.AppMenu = uimenu(app.MaquinaCDIUIFigure);
            app.AppMenu.Text = 'App';
            app.RelatrioMenu = uimenu(app.AppMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.SairMenu = uimenu(app.AppMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.MaquinaCDIUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.MaquinaCDIUIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.GridLayout = uigridlayout(app.pDados);
            app.DadosFunoPanel = uipanel(app.GridLayout);
            app.DadosFunoPanel.Title = 'Dados Função';
            app.fxLabel = uilabel(app.DadosFunoPanel);
            app.fxLabel.Text = 'f(x) = ';
            app.eF = uieditfield(app.DadosFunoPanel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.DadosFunoPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosFunoPanel, 'numeric');
            app.eH = uieditfield(app.DadosFunoPanel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.DadosFunoPanel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.DadosFunoPanel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.DadosFunoPanel);
            app.bLabel.Text = 'b';
            app.bAtualizar = uibutton(app.GridLayout, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.dydx = uilabel(app.GridLayout);
            app.dydx.Interpreter = 'latex';
            app.dydx.Text = '';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.DerivaoNumricaTab = uitab(app.TabGroup2);
            app.DerivaoNumricaTab.Title = 'Derivação Numérica';
            app.ButtonGroup = uibuttongroup(app.DerivaoNumricaTab);
            app.Progressiva2pontosButton = uiradiobutton(app.ButtonGroup);
            app.Progressiva2pontosButton.Text = 'Progressiva (2 pontos)';
            app.Progressiva2pontosButton.Value = true;
            app.Regressiva2pontosButton = uiradiobutton(app.ButtonGroup);
            app.Regressiva2pontosButton.Text = 'Regressiva (2 pontos)';
            app.CentradaButton = uiradiobutton(app.ButtonGroup);
            app.CentradaButton.Text = 'Centrada';
            app.Progressiva3pontosButton = uiradiobutton(app.ButtonGroup);
            app.Progressiva3pontosButton.Text = 'Progressiva (3 pontos)';
            app.Regressiva3pontosButton = uiradiobutton(app.ButtonGroup);
            app.Regressiva3pontosButton.Text = 'Regressiva (3 pontos)';
            app.DerivadaButton = uiradiobutton(app.ButtonGroup);
            app.DerivadaButton.Text = '2ºDerivada';
            app.DiferenasFinitasLabel = uilabel(app.DerivaoNumricaTab);
            app.DiferenasFinitasLabel.Text = 'Diferenças Finitas';
            app.IntegraoNumricaTab = uitab(app.TabGroup2);
            app.IntegraoNumricaTab.Title = 'Integração Numérica';
            app.RegraListBox = uilistbox(app.IntegraoNumricaTab);
            app.RegraListBox.Items = {'Regra dos Trapezios', 'Regra de Simpson'};
            app.RegraListBox.ItemsData = {'1', '2'};
            app.RegraListBox.Value = '1';
            app.eIntN = uieditfield(app.IntegraoNumricaTab, 'numeric');
            app.eIntN.Editable = 'off';
            app.ResultadoLabel = uilabel(app.IntegraoNumricaTab);
            app.ResultadoLabel.Text = 'Resultado:';
            app.IntegraesNumricasLabel = uilabel(app.IntegraoNumricaTab);
            app.IntegraesNumricasLabel.Text = 'Integrações Numéricas';
            app.pResultados = uipanel(app.tgFrVr);
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Gráfico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.DadosFunoPanel_2 = uipanel(app.pDados_2);
            app.DadosFunoPanel_2.Title = 'Dados Função';
            app.fxyLabel = uilabel(app.DadosFunoPanel_2);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.DadosFunoPanel_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.xLabel = uilabel(app.DadosFunoPanel_2);
            app.xLabel.Text = 'x = ';
            app.Label = uilabel(app.DadosFunoPanel_2);
            app.Label.Text = ':';
            app.yLabel = uilabel(app.DadosFunoPanel_2);
            app.yLabel.Text = 'y = ';
            app.aEditFieldLabel = uilabel(app.DadosFunoPanel_2);
            app.aEditFieldLabel.Text = 'c';
            app.h1EditFieldLabel = uilabel(app.DadosFunoPanel_2);
            app.h1EditFieldLabel.Text = 'h2';
            app.Label_2 = uilabel(app.DadosFunoPanel_2);
            app.Label_2.Text = ':';
            app.bEditFieldLabel = uilabel(app.DadosFunoPanel_2);
            app.bEditFieldLabel.Text = 'd';
            app.eC = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eC.Value = -2;
            app.eH2 = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eH2.Value = 0.2;
            app.eD = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eD.Value = 2;
            app.Label_3 = uilabel(app.DadosFunoPanel_2);
            app.Label_3.Text = ':';
            app.Label_4 = uilabel(app.DadosFunoPanel_2);
            app.Label_4.Text = ':';
            app.aEditFieldLabel_2 = uilabel(app.DadosFunoPanel_2);
            app.aEditFieldLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eA_2.Value = -2;
            app.h1EditFieldLabel_2 = uilabel(app.DadosFunoPanel_2);
            app.h1EditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eH1.Value = 0.2;
            app.bEditFieldLabel_2 = uilabel(app.DadosFunoPanel_2);
            app.bEditFieldLabel_2.Text = 'b';
            app.eB_2 = uieditfield(app.DadosFunoPanel_2, 'numeric');
            app.eB_2.Value = 2;
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.dydx2 = uilabel(app.pDados_2);
            app.dydx2.Interpreter = 'latex';
            app.dydx2.Text = '';
            app.harmonica = uilabel(app.pDados_2);
            app.harmonica.Interpreter = 'latex';
            app.harmonica.Text = '';
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, 'Gráfico')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.MaquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaCDIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaCDIUIFigure)
        end
    end
end