classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InterfaceUIMenu                matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        RedeNeuralTab                  matlab.ui.container.Tab
        ResultadosCarregamentoPanel    matlab.ui.container.Panel
        TempoEditField_2               matlab.ui.control.EditField
        TempoEditField_2Label          matlab.ui.control.Label
        PrecisoEditField               matlab.ui.control.EditField
        PrecisoEditFieldLabel          matlab.ui.control.Label
        CarregarRedePanel              matlab.ui.container.Panel
        DataSetDropDown                matlab.ui.control.DropDown
        DataSetDropDownLabel           matlab.ui.control.Label
        CarregarButton                 matlab.ui.control.Button
        NomeRedeEditField_2            matlab.ui.control.EditField
        NomeRedeEditField_2Label       matlab.ui.control.Label
        ConfiguraoPanel                matlab.ui.container.Panel
        DataSetDropDown_2              matlab.ui.control.DropDown
        DataSetDropDown_2Label         matlab.ui.control.Label
        DivideFcnEditField             matlab.ui.control.EditField
        DivideFcnEditFieldLabel        matlab.ui.control.Label
        EpocasEditField                matlab.ui.control.NumericEditField
        EpocasEditFieldLabel           matlab.ui.control.Label
        FunoTreinoDropDown             matlab.ui.control.DropDown
        FunoTreinoDropDownLabel        matlab.ui.control.Label
        FunesAtivaoEditField           matlab.ui.control.EditField
        FunesAtivaoEditFieldLabel      matlab.ui.control.Label
        NeurniosEditField              matlab.ui.control.EditField
        NeurniosEditFieldLabel         matlab.ui.control.Label
        CamadasSpinner                 matlab.ui.control.Spinner
        CamadasSpinnerLabel            matlab.ui.control.Label
        ParametrosPanel                matlab.ui.container.Panel
        TestRatioEditField             matlab.ui.control.NumericEditField
        TestRatioEditFieldLabel        matlab.ui.control.Label
        ValRatioEditField              matlab.ui.control.NumericEditField
        ValRatioEditFieldLabel         matlab.ui.control.Label
        TrainRatioEditField            matlab.ui.control.NumericEditField
        TrainRatioEditFieldLabel       matlab.ui.control.Label
        TreinarButton                  matlab.ui.control.Button
        ResultadosTreinoPanel          matlab.ui.container.Panel
        TempoEditField                 matlab.ui.control.EditField
        TempoEditFieldLabel            matlab.ui.control.Label
        PrecisoTesteEditField_2        matlab.ui.control.EditField
        PrecisoTesteEditField_2Label   matlab.ui.control.Label
        PrecisoGlobalEditField_2       matlab.ui.control.EditField
        PrecisoGlobalEditField_2Label  matlab.ui.control.Label
        GravarRedePanel                matlab.ui.container.Panel
        ConfirmarButton                matlab.ui.control.Button
        NomeRedeEditField              matlab.ui.control.EditField
        NomeRedeEditFieldLabel         matlab.ui.control.Label
        TesteTab                       matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        RedeEditField                  matlab.ui.control.EditField
        RedeEditFieldLabel             matlab.ui.control.Label
        ClassificaoEditField           matlab.ui.control.EditField
        ClassificaoEditFieldLabel      matlab.ui.control.Label
        AplicarRedeButton              matlab.ui.control.Button
        CarregarImagemButton           matlab.ui.control.Button
        MatrizbinariaTextArea          matlab.ui.control.TextArea
        MatrizbinariaTextAreaLabel     matlab.ui.control.Label
        DesenharButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ParametrosPanel.Visible = 'off';
          
         
        end

        % Value changed function: CamadasSpinner
        function CamadasSpinnerValueChanged(app, event)

            
        end

        % Button down function: RedeNeuralTab
        function RedeNeuralTabButtonDown(app, event)
       
        end

        % Value changed function: DivideFcnEditField
        function DivideFcnEditFieldValueChanged(app, event)
            value = app.DivideFcnEditField.Value;
            if strcmpi(value, 'dividerand')
                app.ParametrosPanel.Visible = 'on';
            else
                app.ParametrosPanel.Visible = 'off';
            end
        end

        % Value changed function: DataSetDropDown
        function DataSetDropDownValueChanged(app, event)
            
        end

        % Button pushed function: DesenharButton
        function DesenharButtonPushed(app, event)
            cla(app.UIAxes);                 
            axis(app.UIAxes, [0 20 0 20]);   
            app.UIAxes.XTick = [];           
            app.UIAxes.YTick = [];
            
            
            img = zeros(20, 20);             
          
           
            imshow(img, 'Parent', app.UIAxes);
         
            h = drawfreehand(app.UIAxes);
            
            h.LineWidth = 5;
            mask = createMask(h);
            
          
            mask = imresize(mask, [20 20]);
            vetor = reshape(mask > 0.5, [], 1);  
            
            vetor_str = num2str(vetor(:)');  
            
          
            app.MatrizbinariaTextArea.Value = {vetor_str};
            
        end

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
                  
            savePath = fullfile(pwd, 'ImagesFolder');
            if ~isfolder(savePath)
                uialert(app.UIFigure, 'A pasta "ImagesFolder" não foi encontrada.', 'Erro');
                return;
            end
            
            
            [file, path] = uigetfile('*.png', 'Selecionar Imagem png', savePath);
            
            if isequal(file, 0)
                uialert(app.UIFigure, 'Operação cancelada.', 'Aviso');
                return;
            else
                image = imread(fullfile(path, file));
                mask = imresize(image, [20 20]);
                mask = rgb2gray(mask);  
                mask = imbinarize(mask);  
                vetor = reshape(mask > 0.5, [], 1); 
            
               
                vetor_str = num2str(vetor(:)'); 
            
               
                app.MatrizbinariaTextArea.Value = {vetor_str};
                
                
            end

            
        end

        % Button pushed function: AplicarRedeButton
        function AplicarRedeButtonPushed(app, event)
            vetor_str = app.MatrizbinariaTextArea.Value{1};
            vetor = str2num(vetor_str);  

            if isequal(vetor_str , '')
                uialert(app.UIFigure, 'A imagem nao carregou imagem "Saves" não foi encontrada.', 'Erro');
            end
            rede = app.RedeEditField.Value;
            if(isequal(rede, ''))
                savePath = fullfile(pwd, 'Saves');  
                if ~isfolder(savePath)  
                    uialert(app.UIFigure, 'A pasta "Saves" não foi encontrada.', 'Erro');
                    return;
                end
                [file, path] = uigetfile('*.mat', 'Selecionar rede neuronal treinada', savePath);  
                if isequal(file, 0)
                    uialert(app.UIFigure, 'Operação cancelada.', 'Aviso');
                    return;
                end
                app.RedeEditField.Value = fullfile(path, file);
                data = load(fullfile(path, file));
            else
                 data = load(rede);
            end


            if isfield(data, 'net')
                net = data.net;
            else
                uialert(app.UIFigure, 'O ficheiro selecionado não contém uma rede válida.', 'Erro');
                return;
            end
        
           
            classes = {'Circle' , 'Kite' , 'Parallelogram' , 'Square' , 'Trapezoid', 'Triangle'};
                    
            y = net(vetor'); 
            [~, pred] = max(y);
            
            classe_predita = classes{pred};

            app.ClassificaoEditField.Value = classe_predita;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RedeEditField.Value = '';
        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
          global net
          camadas = app.CamadasSpinner.Value;
            neuronios = str2num(app.NeurniosEditField.Value);
            ativacao = strsplit(app.FunesAtivaoEditField.Value);
            treino = app.FunoTreinoDropDown.Value;
            epocas = app.EpocasEditField.Value;
            divide = app.DivideFcnEditField.Value;
            pasta = app.DataSetDropDown_2.Value;
            
            if isempty(neuronios)
                uialert(app.UIFigure, 'Formato inválido no campo de neurónios.', 'Erro');
                return;
            end
            
           
            if isempty(divide)  
                divide = '';  
                app.PrecisoTesteEditField_2.Visible = 'off';
            else
                app.PrecisoTesteEditField_2.Visible = 'on';
                app.PrecisoTesteEditField_2.Value = '';
                paramTestRatio = app.TestRatioEditField.Value;
                paramValRatio = app.ValRatioEditField.Value;
                paramTrainValue = app.TrainRatioEditField.Value;
                
            end
            
            if length(neuronios) ~= camadas
                uialert(app.UIFigure, 'O número de neurónios deve coincidir com o número de camadas.', 'Erro');
                return;
            end
            
            if length(ativacao) ~= (camadas + 1)
                uialert(app.UIFigure, 'Deves definir uma função de ativação por camada, incluindo a camada de saída.', 'Erro');
                return;
            end
            
            p = [];
            t = [];
            
           
            if isequal(pasta, 'Tudo')
                [p, t] = loadTodasImagens(); 
            else
                baseFolder = fullfile(pwd, 'ImagesFolder');
                selectedFolder = uigetdir(baseFolder, 'Seleciona a subpasta da classe');
                
               
                disp("Pasta:" + selectedFolder);
                [p, t] = loadImagensGeometricas(selectedFolder);  
            end
            disp(p);
            disp("//_________________________________//");
            disp(t);
            tic;
            net = feedforwardnet(neuronios);
            
            
            for i = 1:length(ativacao)
                net.layers{i}.transferFcn = ativacao{i};
            end
            
            net.trainFcn = treino;
            disp(divide);
            net.divideFcn = divide;
            

            if ~isempty(divide)
                net.divideParam.trainRatio = paramTrainValue;
                net.divideParam.valRatio = paramValRatio;
                net.divideParam.testRatio = paramTestRatio;
            end
            
       
            net.trainParam.epochs = epocas;
                    
            [net, tr] = train(net, p, t);
            RedeTreinada = net;
            disp(tr);
            tempoExecucao = toc;
            app.TempoEditField.Value = num2str(tempoExecucao);
            uialert(app.UIFigure, 'Rede treinada com sucesso!', 'Sucesso');


            y_all = net(p);
            [~, pred_all] = max(y_all);
            [~, real_all] = max(t);
            precisaoGlobal = sum(pred_all == real_all) / numel(real_all) * 100;
            disp(precisaoGlobal);
            app.PrecisoGlobalEditField_2.Value = num2str(precisaoGlobal);


             precisaoTeste = NaN;
             if ~isempty(divide)
                 idxTeste = tr.testInd;
                 if ~isempty(idxTeste)
                     y_test = net(p(:, idxTeste));
                     [~, pred_test] = max(y_test);
                     [~, real_test] = max(t(:, idxTeste));
                     precisaoTeste = sum(pred_test == real_test) / numel(real_test) * 100;
                     app.PrecisoTesteEditField_2.Value = num2str(precisaoTeste);
                 end
            end

        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonPushed(app, event)
            global net  

           
            if isempty(net)
                uialert(app.UIFigure, 'Nenhuma rede foi treinada!', 'Erro');
                return;
            end
        
            
            fileName = app.NomeRedeEditField.Value;
        
           
            save(fullfile('Saves', [fileName, '.mat']), 'net');
            
            uialert(app.UIFigure, 'Rede salva com sucesso!', 'Sucesso');
        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            
            baseFolder = fullfile(pwd, 'Saves');
           
            [filename, pathname] = uigetfile(fullfile(baseFolder, '*.mat'), 'Seleciona o arquivo da rede');
            
            
            if filename ~= 0
                
                rede = fullfile(pathname, filename);
                app.NomeRedeEditField_2.Value = rede;
                disp(['Arquivo selecionado: ', rede]);
            
                load(rede, 'net');  
                disp('Rede carregada com sucesso!');
                
            end
            
           
            dataset = app.DataSetDropDown.Value;
           
            p = [];
            t = [];
            
           
            if isequal(dataset, 'Tudo')
                [p, t] = loadTodasImagens();  
            else
                baseFolder = fullfile(pwd, 'ImagesFolder');
                selectedFolder = uigetdir(baseFolder, 'Seleciona a pasta');
                
                if selectedFolder == 0
                    uialert(app.UIFigure, 'Operação cancelada.', 'Aviso');
                    return;
                end
                
                disp("Pasta: " + selectedFolder);
                [p, t] = loadImagensGeometricas(selectedFolder); 
            end
           
            tic;
           
            y_all = sim(net, p);
            
            tempoT = toc;

            [~, pred_all] = max(y_all); 
            [~, real_all] = max(t);      
            
            
            precisaoGlobal = sum(pred_all == real_all) / numel(real_all) * 100;
            
           
            disp(['Precisão global: ', num2str(precisaoGlobal), '%']);
            app.PrecisoEditField.Value = num2str(precisaoGlobal);
            app.TempoEditField_2.Value = num2str(tempoT);

            uialert(app.UIFigure, 'Rede Aplicada com sucesso!', 'Sucesso');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterfaceUIMenu = uimenu(app.UIFigure);
            app.InterfaceUIMenu.Text = 'Interface UI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RedeNeuralTab = uitab(app.TabGroup);
            app.RedeNeuralTab.Title = 'Rede Neural';
            app.RedeNeuralTab.ButtonDownFcn = createCallbackFcn(app, @RedeNeuralTabButtonDown, true);
            app.GravarRedePanel = uipanel(app.RedeNeuralTab);
            app.GravarRedePanel.Title = 'Gravar Rede';
            app.NomeRedeEditFieldLabel = uilabel(app.GravarRedePanel);
            app.NomeRedeEditFieldLabel.Text = 'Nome Rede';
            app.NomeRedeEditField = uieditfield(app.GravarRedePanel, 'text');
            app.ConfirmarButton = uibutton(app.GravarRedePanel, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.ResultadosTreinoPanel = uipanel(app.RedeNeuralTab);
            app.ResultadosTreinoPanel.Title = 'Resultados Treino';
            app.PrecisoGlobalEditField_2Label = uilabel(app.ResultadosTreinoPanel);
            app.PrecisoGlobalEditField_2Label.Text = 'Precisão  Global';
            app.PrecisoGlobalEditField_2 = uieditfield(app.ResultadosTreinoPanel, 'text');
            app.PrecisoGlobalEditField_2.Editable = 'off';
            app.PrecisoTesteEditField_2Label = uilabel(app.ResultadosTreinoPanel);
            app.PrecisoTesteEditField_2Label.Text = 'Precisão  Teste';
            app.PrecisoTesteEditField_2 = uieditfield(app.ResultadosTreinoPanel, 'text');
            app.PrecisoTesteEditField_2.Editable = 'off';
            app.TempoEditFieldLabel = uilabel(app.ResultadosTreinoPanel);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.ResultadosTreinoPanel, 'text');
            app.TempoEditField.Editable = 'off';
            app.ConfiguraoPanel = uipanel(app.RedeNeuralTab);
            app.ConfiguraoPanel.Title = 'Configuração';
            app.TreinarButton = uibutton(app.ConfiguraoPanel, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.ParametrosPanel = uipanel(app.ConfiguraoPanel);
            app.ParametrosPanel.Title = 'Parametros';
            app.TrainRatioEditFieldLabel = uilabel(app.ParametrosPanel);
            app.TrainRatioEditFieldLabel.Text = 'TrainRatio';
            app.TrainRatioEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.ValRatioEditFieldLabel = uilabel(app.ParametrosPanel);
            app.ValRatioEditFieldLabel.Text = 'ValRatio';
            app.ValRatioEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.TestRatioEditFieldLabel = uilabel(app.ParametrosPanel);
            app.TestRatioEditFieldLabel.Text = 'TestRatio';
            app.TestRatioEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.CamadasSpinnerLabel = uilabel(app.ConfiguraoPanel);
            app.CamadasSpinnerLabel.Text = 'Camadas';
            app.CamadasSpinner = uispinner(app.ConfiguraoPanel);
            app.CamadasSpinner.ValueChangedFcn = createCallbackFcn(app, @CamadasSpinnerValueChanged, true);
            app.CamadasSpinner.Value = 1;
            app.NeurniosEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.NeurniosEditFieldLabel.Text = 'Neurónios';
            app.NeurniosEditField = uieditfield(app.ConfiguraoPanel, 'text');
            app.NeurniosEditField.Placeholder = '10 10';
            app.FunesAtivaoEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.FunesAtivaoEditFieldLabel.Text = 'Funções Ativação';
            app.FunesAtivaoEditField = uieditfield(app.ConfiguraoPanel, 'text');
            app.FunesAtivaoEditField.Placeholder = 'logsig softmax';
            app.FunoTreinoDropDownLabel = uilabel(app.ConfiguraoPanel);
            app.FunoTreinoDropDownLabel.Text = 'Função Treino';
            app.FunoTreinoDropDown = uidropdown(app.ConfiguraoPanel);
            app.FunoTreinoDropDown.Items = {'trainbfg', 'trainbr', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainoss', 'trainr', 'trainrp', 'trains', 'trainscg'};
            app.FunoTreinoDropDown.Value = 'trainbfg';
            app.EpocasEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.EpocasEditFieldLabel.Text = 'Epocas';
            app.EpocasEditField = uieditfield(app.ConfiguraoPanel, 'numeric');
            app.EpocasEditField.Limits = [100 200];
            app.EpocasEditField.RoundFractionalValues = 'on';
            app.EpocasEditField.Value = 101;
            app.DivideFcnEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.DivideFcnEditFieldLabel.Text = 'DivideFcn';
            app.DivideFcnEditField = uieditfield(app.ConfiguraoPanel, 'text');
            app.DivideFcnEditField.ValueChangedFcn = createCallbackFcn(app, @DivideFcnEditFieldValueChanged, true);
            app.DataSetDropDown_2Label = uilabel(app.ConfiguraoPanel);
            app.DataSetDropDown_2Label.Text = 'Data Set';
            app.DataSetDropDown_2 = uidropdown(app.ConfiguraoPanel);
            app.DataSetDropDown_2.Items = {'Tudo', 'Pasta'};
            app.DataSetDropDown_2.Value = 'Tudo';
            app.CarregarRedePanel = uipanel(app.RedeNeuralTab);
            app.CarregarRedePanel.Title = 'Carregar Rede';
            app.NomeRedeEditField_2Label = uilabel(app.CarregarRedePanel);
            app.NomeRedeEditField_2Label.Text = 'Nome Rede';
            app.NomeRedeEditField_2 = uieditfield(app.CarregarRedePanel, 'text');
            app.NomeRedeEditField_2.Editable = 'off';
            app.CarregarButton = uibutton(app.CarregarRedePanel, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.DataSetDropDownLabel = uilabel(app.CarregarRedePanel);
            app.DataSetDropDownLabel.Text = 'Data Set';
            app.DataSetDropDown = uidropdown(app.CarregarRedePanel);
            app.DataSetDropDown.Items = {'Tudo', 'Pasta'};
            app.DataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSetDropDownValueChanged, true);
            app.DataSetDropDown.Value = 'Tudo';
            app.ResultadosCarregamentoPanel = uipanel(app.RedeNeuralTab);
            app.ResultadosCarregamentoPanel.Title = 'Resultados Carregamento';
            app.PrecisoEditFieldLabel = uilabel(app.ResultadosCarregamentoPanel);
            app.PrecisoEditFieldLabel.Text = 'Precisão';
            app.PrecisoEditField = uieditfield(app.ResultadosCarregamentoPanel, 'text');
            app.PrecisoEditField.Editable = 'off';
            app.TempoEditField_2Label = uilabel(app.ResultadosCarregamentoPanel);
            app.TempoEditField_2Label.Text = 'Tempo';
            app.TempoEditField_2 = uieditfield(app.ResultadosCarregamentoPanel, 'text');
            app.TempoEditField_2.Editable = 'off';
            app.TesteTab = uitab(app.TabGroup);
            app.TesteTab.Title = 'Teste';
            app.Panel = uipanel(app.TesteTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DesenharButton = uibutton(app.Panel, 'push');
            app.DesenharButton.ButtonPushedFcn = createCallbackFcn(app, @DesenharButtonPushed, true);
            app.DesenharButton.Text = 'Desenhar';
            app.MatrizbinariaTextAreaLabel = uilabel(app.Panel);
            app.MatrizbinariaTextAreaLabel.Text = 'Matriz binaria';
            app.MatrizbinariaTextArea = uitextarea(app.Panel);
            app.CarregarImagemButton = uibutton(app.Panel, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            app.AplicarRedeButton = uibutton(app.Panel, 'push');
            app.AplicarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRedeButtonPushed, true);
            app.AplicarRedeButton.Text = 'Aplicar Rede';
            app.ClassificaoEditFieldLabel = uilabel(app.Panel);
            app.ClassificaoEditFieldLabel.Text = 'Classificação';
            app.ClassificaoEditField = uieditfield(app.Panel, 'text');
            app.ClassificaoEditField.Editable = 'off';
            app.RedeEditFieldLabel = uilabel(app.Panel);
            app.RedeEditFieldLabel.Text = 'Rede';
            app.RedeEditField = uieditfield(app.Panel, 'text');
            app.RedeEditField.Editable = 'off';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end